classdef DUAL_ANAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel_Load_Data       matlab.ui.container.Panel
        BrowseButton          matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        LoadDataEditField     matlab.ui.control.EditField
        LogTextArea           matlab.ui.control.TextArea
        SaveDataButton        matlab.ui.control.Button
        SaveOpsButton         matlab.ui.control.Button
        LoadButton            matlab.ui.control.Button
        Label_2               matlab.ui.control.Label
        LoadDataEditField_IM  matlab.ui.control.EditField
        BrowseButton_IM       matlab.ui.control.Button
        ExitButton            matlab.ui.control.Button
        TabGroup              matlab.ui.container.TabGroup
        DUALTab               matlab.ui.container.Tab
        EPHYS_TAB             matlab.ui.container.TabGroup
        RAWETab               matlab.ui.container.Tab
        UIAxesE_RAW           matlab.ui.control.UIAxes
        SPECTROGRAMTab        matlab.ui.container.Tab
        UIAxes2_2             matlab.ui.control.UIAxes
        POWERTab              matlab.ui.container.Tab
        UIAxes2_3             matlab.ui.control.UIAxes
        THETATab              matlab.ui.container.Tab
        UIAxes2_4             matlab.ui.control.UIAxes
        SWRTab                matlab.ui.container.Tab
        UIAxes2_5             matlab.ui.control.UIAxes
        CUSTOMTab             matlab.ui.container.Tab
        TabGroup3             matlab.ui.container.TabGroup
        RAWTab                matlab.ui.container.Tab
        UIAxesRAWCa           matlab.ui.control.UIAxes
        CORRECTEDTab          matlab.ui.container.Tab
        DFDTTab               matlab.ui.container.Tab
        SMOOTHEDDFDTTab       matlab.ui.container.Tab
        INFERREDSPIKESTab     matlab.ui.container.Tab
        EPHYSTab              matlab.ui.container.Tab
        UIAxes1               matlab.ui.control.UIAxes
        IMAGETab              matlab.ui.container.Tab
        ENSEMBLETab           matlab.ui.container.Tab
        BEHAVIORTab           matlab.ui.container.Tab
        ROISCALEButtonGroup   matlab.ui.container.ButtonGroup
        NEURONButton          matlab.ui.control.ToggleButton
        ENSEMBLEButton        matlab.ui.control.ToggleButton
        POPULATIONButton      matlab.ui.control.ToggleButton
        Image                 matlab.ui.control.Image
        STUFFListBoxLabel     matlab.ui.control.Label
        STUFFListBox          matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        gui_path;
        ops = struct;
        log;
        log_line=1;
        file_loc;
        file_locIM;
        MView = struct();
        AnalogData = struct();
        RawImagingData = struct();
        f;
        mfile;
        openResult;
        ImData=struct();
 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                 pwd2 = fileparts(which('DUAL_ANAL.mlapp'));
                 %pwd2 = pwd;
            
            if exist([pwd2 '\Dual_Anal_Backend'], 'dir')
                addpath([pwd2 '\Dual_Anal_Backend\Dual_Anal_functions']);
                addpath([pwd2 '\Dual_Anal_Backend\Dual_Anal_options']);
                app.gui_path = [pwd2 '\Dual_Anal_options.mat'];
            else
                error('RAFA: You need to move to Dual Anal directory and reopen GUI!!!');
            end    
                
            f_DA_startup(app);
        end

        % Callback function: BrowseButton, LoadDataEditField
        function BrowseButtonPushed(app, event)
            f_DA_browse_button(app);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            f_DA_save_data(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            f_DA_load_button_pushed(app);
            f_DA_rawE(app);
            f_DA_Raw_F(app);
        end

        % Button pushed function: SaveOpsButton
        function SaveOpsButtonPushed(app, event)
            app.ops = f_DA_collect_ops(app);
            ops = app.ops;
             try 
                if ~exist(app.ops.ops_path, 'file')
                    save(app.ops.ops_path, 'ops');
                else
                    save(app.ops.ops_path, 'ops', '-append');
                end
                f_DA_update_log(app, ['Ops saved in: ' app.ops.ops_path]);
            catch
                f_DA_update_log(app, ['Unable to save ops in ' app.ops.ops_path]);
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
           MView=app.MView;
           AnalogData = app.AnalogData;
           ops = app.ops;
           file_loc = app.file_loc;
           clear f;
           clear mfile;
           clear app.f;
           clear app.mfile;
           save('data.mat','MView','ops','AnalogData','file_loc');
           app.delete;
        end

        % Callback function
        function PlotButtonPressed(app, event)
            f_DA_plotpress(app);
        end

        % Button pushed function: BrowseButton_IM
        function BrowseButtonIMPushed(app, event)
            f_DA_browse_buttonIM(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_Load_Data = uipanel(app.UIFigure);
            app.BrowseButton = uibutton(app.Panel_Load_Data, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = {'Browse'; ''};
            app.Label = uilabel(app.Panel_Load_Data);
            app.Label.Text = {''; ''};
            app.LoadDataEditField = uieditfield(app.Panel_Load_Data, 'text');
            app.LoadDataEditField.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.LoadDataEditField.Value = 'Load ''.MDF'' file with MSCAN outputs';
            app.LogTextArea = uitextarea(app.Panel_Load_Data);
            app.SaveDataButton = uibutton(app.Panel_Load_Data, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.SaveOpsButton = uibutton(app.Panel_Load_Data, 'push');
            app.SaveOpsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOpsButtonPushed, true);
            app.SaveOpsButton.Text = 'Save Options';
            app.LoadButton = uibutton(app.Panel_Load_Data, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Label_2 = uilabel(app.Panel_Load_Data);
            app.Label_2.Text = {''; ''};
            app.LoadDataEditField_IM = uieditfield(app.Panel_Load_Data, 'text');
            app.LoadDataEditField_IM.Value = 'Load ''Fall.mat'' file with Suite2P outputs';
            app.BrowseButton_IM = uibutton(app.Panel_Load_Data, 'push');
            app.BrowseButton_IM.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonIMPushed, true);
            app.BrowseButton_IM.Text = {'Browse'; ''};
            app.ExitButton = uibutton(app.Panel_Load_Data, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DUALTab = uitab(app.TabGroup);
            app.DUALTab.Title = 'DUAL';
            app.EPHYS_TAB = uitabgroup(app.DUALTab);
            app.RAWETab = uitab(app.EPHYS_TAB);
            app.RAWETab.Title = 'RAW';
            app.UIAxesE_RAW = uiaxes(app.RAWETab);
            title(app.UIAxesE_RAW, 'Local Field Potential (Raw)')
            xlabel(app.UIAxesE_RAW, 'Time')
            ylabel(app.UIAxesE_RAW, 'Signal')
            app.SPECTROGRAMTab = uitab(app.EPHYS_TAB);
            app.SPECTROGRAMTab.Title = 'SPECTROGRAM';
            app.UIAxes2_2 = uiaxes(app.SPECTROGRAMTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.POWERTab = uitab(app.EPHYS_TAB);
            app.POWERTab.Title = 'POWER';
            app.UIAxes2_3 = uiaxes(app.POWERTab);
            title(app.UIAxes2_3, 'Title')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.THETATab = uitab(app.EPHYS_TAB);
            app.THETATab.Title = 'THETA';
            app.UIAxes2_4 = uiaxes(app.THETATab);
            title(app.UIAxes2_4, 'Title')
            xlabel(app.UIAxes2_4, 'X')
            ylabel(app.UIAxes2_4, 'Y')
            zlabel(app.UIAxes2_4, 'Z')
            app.SWRTab = uitab(app.EPHYS_TAB);
            app.SWRTab.Title = 'SWR';
            app.UIAxes2_5 = uiaxes(app.SWRTab);
            title(app.UIAxes2_5, 'Title')
            xlabel(app.UIAxes2_5, 'X')
            ylabel(app.UIAxes2_5, 'Y')
            zlabel(app.UIAxes2_5, 'Z')
            app.CUSTOMTab = uitab(app.EPHYS_TAB);
            app.CUSTOMTab.Title = 'CUSTOM';
            app.TabGroup3 = uitabgroup(app.DUALTab);
            app.RAWTab = uitab(app.TabGroup3);
            app.RAWTab.Title = 'RAW';
            app.UIAxesRAWCa = uiaxes(app.RAWTab);
            title(app.UIAxesRAWCa, 'Raw Fluorescence')
            xlabel(app.UIAxesRAWCa, 'Time')
            ylabel(app.UIAxesRAWCa, 'Signal')
            app.CORRECTEDTab = uitab(app.TabGroup3);
            app.CORRECTEDTab.Title = 'CORRECTED';
            app.DFDTTab = uitab(app.TabGroup3);
            app.DFDTTab.Title = 'DF/DT';
            app.SMOOTHEDDFDTTab = uitab(app.TabGroup3);
            app.SMOOTHEDDFDTTab.Title = 'SMOOTHED DF/DT';
            app.INFERREDSPIKESTab = uitab(app.TabGroup3);
            app.INFERREDSPIKESTab.Title = 'INFERRED SPIKES';
            app.EPHYSTab = uitab(app.TabGroup);
            app.EPHYSTab.Title = 'EPHYS';
            app.UIAxes1 = uiaxes(app.EPHYSTab);
            title(app.UIAxes1, 'Analog')
            xlabel(app.UIAxes1, 'Time')
            ylabel(app.UIAxes1, 'Signal')
            app.IMAGETab = uitab(app.TabGroup);
            app.IMAGETab.Title = 'IMAGE';
            app.ENSEMBLETab = uitab(app.TabGroup);
            app.ENSEMBLETab.Title = 'ENSEMBLE';
            app.BEHAVIORTab = uitab(app.TabGroup);
            app.BEHAVIORTab.Title = 'BEHAVIOR';
            app.ROISCALEButtonGroup = uibuttongroup(app.UIFigure);
            app.ROISCALEButtonGroup.Title = 'ROI SCALE';
            app.NEURONButton = uitogglebutton(app.ROISCALEButtonGroup);
            app.NEURONButton.Text = 'NEURON';
            app.NEURONButton.Value = true;
            app.ENSEMBLEButton = uitogglebutton(app.ROISCALEButtonGroup);
            app.ENSEMBLEButton.Text = 'ENSEMBLE';
            app.POPULATIONButton = uitogglebutton(app.ROISCALEButtonGroup);
            app.POPULATIONButton.Text = 'POPULATION';
            app.Image = uiimage(app.UIFigure);
            app.STUFFListBoxLabel = uilabel(app.UIFigure);
            app.STUFFListBoxLabel.Text = 'STUFF';
            app.STUFFListBox = uilistbox(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DUAL_ANAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end